classdef appfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        LeonardoTortatoLetciaCruzLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        SecondAnalysisButton_2          matlab.ui.control.Button
        FirstAnalysisButton_2           matlab.ui.control.Button
        StJosephCountySocioeconomicInsightsToolLabel_5  matlab.ui.control.Label
        Q1Tab                           matlab.ui.container.Tab
        HomeownershipRentershipandIncomeRelationshipLabel  matlab.ui.control.Label
        StJosephCountySocioeconomicInsightsToolLabel_3  matlab.ui.control.Label
        MinimumIncomeSlider             matlab.ui.control.Slider
        MinimumIncomeSliderLabel        matlab.ui.control.Label
        WhatdoyouwanttodisplaySwitch    matlab.ui.control.Switch
        WhatdoyouwanttodisplaySwitchLabel  matlab.ui.control.Label
        TrendlineonCheckBox             matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        Q2Tab                           matlab.ui.container.Tab
        RaceLabel                       matlab.ui.control.Label
        OtherRaceCheckBox               matlab.ui.control.CheckBox
        PacificIslanderCheckBox         matlab.ui.control.CheckBox
        AsianCheckBox                   matlab.ui.control.CheckBox
        WhiteCheckBox                   matlab.ui.control.CheckBox
        BlackCheckBox                   matlab.ui.control.CheckBox
        MaximumIncomeEditField          matlab.ui.control.NumericEditField
        MaximumIncomeLabel              matlab.ui.control.Label
        MinimumIncomeEditField          matlab.ui.control.NumericEditField
        MinimumIncomeLabel              matlab.ui.control.Label
        PLOTButton                      matlab.ui.control.Button
        HomeowershipandRaceRelationshipLabel  matlab.ui.control.Label
        StJosephCountySocioeconomicInsightsToolLabel_2  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data % to storee our table
    end

    methods (Access = private)

        function updatePlot(app)
            % Pull median household income and total occupied housing units from dataset
            % Income: SE_A14006_001
            % Total Occupied: SE_A10060_001
            % Owner Occupied: SE_A10060_002
            % Renter Occupied: SE_A10060_003
             
            income = app.Data.SE_A14006_001;
            totalUnits = app.Data.SE_A10060_001;

            % Keep only rows where income meets the slider threshold,
            % total units is nonzero, and income is not NaN
            minIncome = app.MinimumIncomeSlider.Value;
            idx = (income >= minIncome) & (totalUnits > 0) & (~isnan(income));

            % Apply the logical index to get filtered vectors
            fIncome = income(idx);
            fTotal = totalUnits(idx);

            % Compute ownership or rentership rate (%) depending on switch position
            if strcmp(app.WhatdoyouwanttodisplaySwitch.Value, 'Homeownership')
                % Owner-occupied units as a share of total occupied units
                yData = (app.Data.SE_A10060_002(idx) ./ fTotal) * 100;
                yLabelStr = 'Homeownership Rate (%)';
            else
                % Renter-occupied units as a share of total occupied units
                yData = (app.Data.SE_A10060_003(idx) ./ fTotal) * 100;
                yLabelStr = 'Rentership Rate (%)';
            end

            % Clear previous axes content, then draw a filled scatter plot
            cla(app.UIAxes);
            scatter(app.UIAxes, fIncome, yData, 40, 'filled');

            % Label axes; suppress scientific notation and format as currency / percent
            ylabel(app.UIAxes, yLabelStr);
            xlabel(app.UIAxes, 'Median Household Income ($)');
            app.UIAxes.XAxis.Exponent = 0;        % full numeric display on x-axis
            xtickformat(app.UIAxes, '$%,.0f');    % Dollar format with comma separator
            ytickformat(app.UIAxes, '%.0f%%');    % Integer percent format

            % Trendline
            % Draw a linear trendline if checkbox is enabled
            if app.TrendlineonCheckBox.Value && length(yData) > 1
                hold(app.UIAxes, 'on');
                % first-degree polynomial to the unfiltered-income / yData pair
                p = polyfit(income(idx), yData, 1);
                % Evaluate and plot the trendline in gold over the same x
                % range
                plot(app.UIAxes, income(idx), polyval(p, income(idx)), ...
                    'LineWidth', 2, ...
                    'Color', [0.929, 0.694, 0.125]);
                hold(app.UIAxes, 'off');
            end

        end

        function updateUIChange(app, event)
            updatePlot(app);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MinimumIncomeSlider.ValueChangedFcn = createCallbackFcn(app, @updateUIChange, true);
            app.WhatdoyouwanttodisplaySwitch.ValueChangedFcn = createCallbackFcn(app, @updateUIChange, true);
            app.TrendlineonCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateUIChange, true);
            
            % Read census tract data from Excel into a table stored on the app object
            % so all methods can access it without reloading from disk each
            % time
            app.Data = readtable('CensusTract_Data.xlsx');

            % Set slider range dynamically from the actual income column
            % ignoring NaN entries so missing data doesn't corrupt the bounds
            minVal = min(app.Data.SE_A14006_001, [], 'omitnan');
            maxVal = max(app.Data.SE_A14006_001, [], 'omitnan');
            app.MinimumIncomeSlider.Limits = [minVal, maxVal];
            app.MinimumIncomeSlider.Value = minVal;

            % Show only the two endpoints as tick labels to keep the slider uncluttered;
            % format both as dollar amounts rounded to the nearest whole number
            app.MinimumIncomeSlider.MajorTicks = [minVal, maxVal];
            app.MinimumIncomeSlider.MajorTickLabels = {['$' num2str(round(minVal))], ['$' num2str(round(maxVal))]};

            updatePlot(app);
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            plotFuncQ2(app);
        end

        % Button pushed function: FirstAnalysisButton_2
        function FirstAnalysisButton_2Pushed(app, event)
            % Changes the tab group to the Q1 tab
            app.TabGroup.SelectedTab = app.Q1Tab;
        end

        % Button pushed function: SecondAnalysisButton_2
        function SecondAnalysisButton_2Pushed(app, event)
            % Changes the tab group to the Q2 tab
            app.TabGroup.SelectedTab = app.Q2Tab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.StJosephCountySocioeconomicInsightsToolLabel_5 = uilabel(app.HomeTab);
            app.StJosephCountySocioeconomicInsightsToolLabel_5.Text = {'St. Joseph County '; 'Socioeconomic Insights Tool'};
            app.FirstAnalysisButton_2 = uibutton(app.HomeTab, 'push');
            app.FirstAnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @FirstAnalysisButton_2Pushed, true);
            app.FirstAnalysisButton_2.Text = 'First Analysis';
            app.SecondAnalysisButton_2 = uibutton(app.HomeTab, 'push');
            app.SecondAnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @SecondAnalysisButton_2Pushed, true);
            app.SecondAnalysisButton_2.Text = 'Second Analysis';
            app.Image2 = uiimage(app.HomeTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Indiana_State_Flag_Emblem.svg.png');
            app.LeonardoTortatoLetciaCruzLabel = uilabel(app.HomeTab);
            app.LeonardoTortatoLetciaCruzLabel.Text = 'Leonardo Tortato & Letícia Cruz ';
            app.Q1Tab = uitab(app.TabGroup);
            app.Q1Tab.Title = 'Q1';
            app.UIAxes = uiaxes(app.Q1Tab);
            xlabel(app.UIAxes, 'Median Income ($)')
            ylabel(app.UIAxes, '##### Rate (%)')
            zlabel(app.UIAxes, 'Z')
            app.TrendlineonCheckBox = uicheckbox(app.Q1Tab);
            app.TrendlineonCheckBox.Text = 'Trendline on?';
            app.WhatdoyouwanttodisplaySwitchLabel = uilabel(app.Q1Tab);
            app.WhatdoyouwanttodisplaySwitchLabel.Text = 'Y-Axis Display';
            app.WhatdoyouwanttodisplaySwitch = uiswitch(app.Q1Tab, 'slider');
            app.WhatdoyouwanttodisplaySwitch.Items = {'Homeownership', 'Rentership'};
            app.WhatdoyouwanttodisplaySwitch.Value = 'Homeownership';
            app.MinimumIncomeSliderLabel = uilabel(app.Q1Tab);
            app.MinimumIncomeSliderLabel.Text = 'Minimum Income';
            app.MinimumIncomeSlider = uislider(app.Q1Tab);
            app.MinimumIncomeSlider.Limits = [0 10000000];
            app.MinimumIncomeSlider.Step = 1000;
            app.StJosephCountySocioeconomicInsightsToolLabel_3 = uilabel(app.Q1Tab);
            app.StJosephCountySocioeconomicInsightsToolLabel_3.Text = 'St. Joseph County Socioeconomic Insights Tool';
            app.HomeownershipRentershipandIncomeRelationshipLabel = uilabel(app.Q1Tab);
            app.HomeownershipRentershipandIncomeRelationshipLabel.Text = 'Homeownership, Rentership and Income Relationship';
            app.Q2Tab = uitab(app.TabGroup);
            app.Q2Tab.Title = 'Q2';
            app.UIAxes2 = uiaxes(app.Q2Tab);
            xlabel(app.UIAxes2, 'Race')
            ylabel(app.UIAxes2, 'Number of Individuals')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.StJosephCountySocioeconomicInsightsToolLabel_2 = uilabel(app.Q2Tab);
            app.StJosephCountySocioeconomicInsightsToolLabel_2.Text = 'St. Joseph County Socioeconomic Insights Tool';
            app.HomeowershipandRaceRelationshipLabel = uilabel(app.Q2Tab);
            app.HomeowershipandRaceRelationshipLabel.Text = 'Homeowership and Race Relationship';
            app.PLOTButton = uibutton(app.Q2Tab, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.MinimumIncomeLabel = uilabel(app.Q2Tab);
            app.MinimumIncomeLabel.Text = 'Minimum Income';
            app.MinimumIncomeEditField = uieditfield(app.Q2Tab, 'numeric');
            app.MinimumIncomeEditField.Limits = [0 Inf];
            app.MinimumIncomeEditField.ValueDisplayFormat = '%0.f';
            app.MaximumIncomeLabel = uilabel(app.Q2Tab);
            app.MaximumIncomeLabel.Text = 'Maximum Income';
            app.MaximumIncomeEditField = uieditfield(app.Q2Tab, 'numeric');
            app.MaximumIncomeEditField.Limits = [0 Inf];
            app.MaximumIncomeEditField.ValueDisplayFormat = '%0.f';
            app.BlackCheckBox = uicheckbox(app.Q2Tab);
            app.BlackCheckBox.Text = 'Black';
            app.WhiteCheckBox = uicheckbox(app.Q2Tab);
            app.WhiteCheckBox.Text = 'White';
            app.AsianCheckBox = uicheckbox(app.Q2Tab);
            app.AsianCheckBox.Text = 'Asian';
            app.PacificIslanderCheckBox = uicheckbox(app.Q2Tab);
            app.PacificIslanderCheckBox.Text = 'Pacific Islander';
            app.OtherRaceCheckBox = uicheckbox(app.Q2Tab);
            app.OtherRaceCheckBox.Text = 'Other Race';
            app.RaceLabel = uilabel(app.Q2Tab);
            app.RaceLabel.Text = 'Race';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end